classdef sleep_stimulation_protocol_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcqStimControllerUIFigure      matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        GeneralPanel                   matlab.ui.container.Panel
        PathEditFieldLabel             matlab.ui.control.Label
        path                           matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        filename                       matlab.ui.control.EditField
        StimulationSettingsPanel       matlab.ui.container.Panel
        ContactIDEditField_2Label      matlab.ui.control.Label
        id_2                           matlab.ui.control.NumericEditField
        Phase2DelayusecLabel           matlab.ui.control.Label
        delay_2                        matlab.ui.control.NumericEditField
        Phase2AmpmAEditFieldLabel      matlab.ui.control.Label
        amp_2                          matlab.ui.control.NumericEditField
        Phase2WidthmsecEditFieldLabel  matlab.ui.control.Label
        width_2                        matlab.ui.control.NumericEditField
        StimulationFreqHzEditFieldLabel  matlab.ui.control.Label
        stimFreq                       matlab.ui.control.NumericEditField
        ContactIDEditFieldLabel        matlab.ui.control.Label
        id_1                           matlab.ui.control.NumericEditField
        StimulationRepeatminLabel      matlab.ui.control.Label
        repeatDur                      matlab.ui.control.NumericEditField
        SimulationDurationsecEditFieldLabel  matlab.ui.control.Label
        stimDur                        matlab.ui.control.NumericEditField
        Phase1WidthmsecEditFieldLabel  matlab.ui.control.Label
        width_1                        matlab.ui.control.NumericEditField
        Phase1AmpmAEditFieldLabel      matlab.ui.control.Label
        amp_1                          matlab.ui.control.NumericEditField
        Phase1DelayusecLabel           matlab.ui.control.Label
        delay_1                        matlab.ui.control.NumericEditField
        SystemMACEditFieldLabel        matlab.ui.control.Label
        mac                            matlab.ui.control.EditField
        ActionsandStatusTab            matlab.ui.container.Tab
        connectButton                  matlab.ui.control.Button
        startRecButton                 matlab.ui.control.Button
        stopRecButton                  matlab.ui.control.Button
        StatusTextAreaLabel            matlab.ui.control.Label
        statusField                    matlab.ui.control.TextArea
        disconnectButton               matlab.ui.control.Button
        startStimButton                matlab.ui.control.Button
        stopStimButton                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        stimTimer % timer for deciding when to simulate
    end
    
    methods (Access = private)
        
        function addToStatus(app, text)
            app.statusField.Value = [ ...
                app.statusField.Value;...
                {[ datestr(now, 'HH:MM:SS.FFF') '  ' text ]}; ...
                ];
        end
        
        function stimulate(app)
            
            if app.stopStimButton.Value  % flag has been set to stop stimulating
                app.addToStatus('Stimulation has ended.');
                return
            end
            
            ret = AO_IsConnected();
            
            if ret ~= 1 
                app.addToStatus(sprintf('Error: connection status is "%d". Aborting.', ret ));
                app.stop_recording();
                app.setButtons(1,0,0,0,0,0);
                return
            else
                ret = AO_StartDigitalStimulation(app.id_1.Value,...
                                                 app.delay_1.Value,...
                                                 app.amp_1.Value,...
                                                 app.width_1.Value,...
                                                 app.delay_2.Value,...
                                                 app.amp_2.Value,...
                                                 app.width_2.Value,...
                                                 app.stimFreq.Value,...
                                                 app.stimDur.Value,...
                                                 app.id_2.Value );
                app.addToStatus(['Stimulation returned', num2str(ret)]);
            end
            
        end
        
        function setButtons(app,a,b,c,d,e,f)
            app.connectButton.Enable     = a;
            app.disconnectButton.Enable  = b;
            app.startRecButton.Enable    = c;
            app.stopRecButton.Enable     = d;
            app.startStimButton.Enable   = e;
            app.stopStimButton.Enable    = f;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            addpath('c:\Program Files (x86)\AlphaOmega\Neuro Omega System SDK\MATLAB_SDK\');
        end

        % Button pushed function: connectButton
        function connect(app, event)
            %% initiate connection
            app.addToStatus('Attempting to connect');
            try
                ret = AO_DefaultStartConnection(app.mac.Value);
            catch e
                app.addToStatus(['Connection failed with error: ' e.message ]);
                return
            end
            if( ret == 10 )
                app.addToStatus('Discovered existing connection.');
            elseif( ret )
                app.addToStatus(['Connection failed with return value ' num2str(ret)]);
                return
            else
                app.addToStatus('Connection in progress...');
            end
            
            %% ensure connection complete
            for j=1:5
                pause(1);
                ret = AO_IsConnected();
                if( ret == 1 )
                    app.addToStatus('Connection completed.');
                    break;
                end
            end
            
            if( ret ~= 1 )
                app.addToStatus('Unable to complete connection.');
                return;
            end
            
            %% is connected, proceed
            app.setButtons(0,1,1,0,0,0);
            
        end

        % Close request function: AcqStimControllerUIFigure
        function finalize(app, event)
            if( app.stopStimButton.Enable )
                app.stop_stim();
            end
            if( app.stopRecButton.Enable )
                app.stop_recording();
            end
            delete(app)
            AO_CloseConnection();
        end

        % Button pushed function: startRecButton
        function start_recording(app, event)
            %% double check overwrite last file
            %fig = uifigure;
            %selection = uiconfirm(fig,'Starting recording will overwrite last file with same name.','Confirm Start',...
            %  'Icon','warning','Options',{'Continue','Cancel'});
            
            ret = AO_SetSavePath(app.path.Value);
            if( ret )
                app.addToStatus('Error setting save path');
                return
            else
                app.addToStatus(['Path set to ' app.path.Value ]);
            end
            
            ret = AO_SetSaveFileName(app.filename.Value);
            if( ret )
                app.addToStatus('Error setting file name');
                return
            else
                app.addToStatus(['Filename set to ' app.filename.Value ]);
            end
            
            ret = AO_StartSave();
            
            if( ret )
                app.addToStatus(['Failed to start data acquisition. Error code ', str2double(ret) ]);
                return
            end
            
            app.addToStatus('Started data acquisition.');
            app.setButtons(0,0,0,1,1,0);
            
        end

        % Button pushed function: stopRecButton
        function stop_recording(app, event)
            if( app.stopStimButton.Enable == false )
                app.stop_stim();
            end
            
            ret = AO_StopSave();
            app.addToStatus(['Stopped data acquisition (' num2str(ret) ')']);
            
            app.setButtons(0,1,1,0,0,0);
        end

        % Button pushed function: disconnectButton
        function disconnect(app, event)
            ret = AO_CloseConnection();
            app.addToStatus(['Connection closed (' num2str(ret) ')' ]);
            app.setButtons(1,0,0,0,0,0);
        end

        % Button pushed function: startStimButton
        function start_stim(app, event)
            app.stimTimer = timer('TimerFcn',';','StartDelay',app.repeatDur.Value*60);
            
            app.addToStatus(['Stimulation to repeat every ' num2str(app.repeatDur.Value) ' minutes.']);
            app.setButtons(0,0,0,1,0,1);
            
            while( ~app.stopStimButton.Value )
                start(app.stimTimer);
                wait(app.stimTimer);
                if( app.stopStimButton.Value )
                    app.stopStimButton.Value = false;
                    break;
                end
                app.stimulate();
            end
        end

        % Value changed function: stopStimButton
        function stop_stim(app, event)
            stop(app.stimTimer);
            wait(app.stimTimer);
            app.setButtons(0,0,0,1,1,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcqStimControllerUIFigure = uifigure('Visible', 'off');
            app.AcqStimControllerUIFigure.Name = 'Acq/Stim Controller';
            app.AcqStimControllerUIFigure.CloseRequestFcn = createCallbackFcn(app, @finalize, true);
            app.TabGroup = uitabgroup(app.AcqStimControllerUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GeneralPanel = uipanel(app.ParametersTab);
            app.GeneralPanel.Title = 'General';
            app.PathEditFieldLabel = uilabel(app.GeneralPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.path = uieditfield(app.GeneralPanel, 'text');
            app.path.Value = 'c:\Research_Data\UNMC-0000';
            app.FilenameEditFieldLabel = uilabel(app.GeneralPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.filename = uieditfield(app.GeneralPanel, 'text');
            app.filename.Value = 'night-3';
            app.StimulationSettingsPanel = uipanel(app.ParametersTab);
            app.StimulationSettingsPanel.Title = 'Stimulation Settings';
            app.ContactIDEditField_2Label = uilabel(app.StimulationSettingsPanel);
            app.ContactIDEditField_2Label.Text = 'ContactID (-)';
            app.id_2 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.id_2.Limits = [10000 10100];
            app.id_2.ValueDisplayFormat = '%.0f';
            app.id_2.Value = 10006;
            app.Phase2DelayusecLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase2DelayusecLabel.Text = 'Phase 2 Delay [usec]';
            app.delay_2 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.delay_2.Limits = [0 800];
            app.delay_2.Value = 80;
            app.Phase2AmpmAEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase2AmpmAEditFieldLabel.Text = 'Phase 2 Amp [mA]';
            app.amp_2 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.amp_2.Limits = [0 700];
            app.amp_2.Value = 1;
            app.Phase2WidthmsecEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase2WidthmsecEditFieldLabel.Text = 'Phase 2 Width [msec]';
            app.width_2 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.width_2.LowerLimitInclusive = 'off';
            app.width_2.Limits = [0 800];
            app.width_2.Value = 90;
            app.StimulationFreqHzEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.StimulationFreqHzEditFieldLabel.Text = 'Stimulation Freq [Hz]';
            app.stimFreq = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.stimFreq.Limits = [0 250];
            app.stimFreq.Value = 185;
            app.ContactIDEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.ContactIDEditFieldLabel.Text = 'Contact ID (+)';
            app.id_1 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.id_1.Limits = [10000 10100];
            app.id_1.ValueDisplayFormat = '%.0f';
            app.id_1.Value = 10005;
            app.StimulationRepeatminLabel = uilabel(app.StimulationSettingsPanel);
            app.StimulationRepeatminLabel.Text = 'Stimulation Repeat [min]';
            app.repeatDur = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.repeatDur.Value = 5;
            app.SimulationDurationsecEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.SimulationDurationsecEditFieldLabel.Text = 'Simulation Duration [sec]';
            app.stimDur = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.stimDur.Limits = [0 60];
            app.stimDur.Value = 30;
            app.Phase1WidthmsecEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase1WidthmsecEditFieldLabel.Text = 'Phase 1 Width [msec]';
            app.width_1 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.width_1.LowerLimitInclusive = 'off';
            app.width_1.Limits = [0 800];
            app.width_1.Value = 90;
            app.Phase1AmpmAEditFieldLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase1AmpmAEditFieldLabel.Text = 'Phase 1 Amp [mA]';
            app.amp_1 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.amp_1.Limits = [-5 5];
            app.amp_1.Value = 1;
            app.Phase1DelayusecLabel = uilabel(app.StimulationSettingsPanel);
            app.Phase1DelayusecLabel.Text = 'Phase 1 Delay [usec]';
            app.delay_1 = uieditfield(app.StimulationSettingsPanel, 'numeric');
            app.delay_1.Limits = [0 800];
            app.delay_1.Value = 90;
            app.SystemMACEditFieldLabel = uilabel(app.ParametersTab);
            app.SystemMACEditFieldLabel.Text = 'System MAC';
            app.mac = uieditfield(app.ParametersTab, 'text');
            app.mac.Value = 'a8:1b:6a:27:dc:e9';
            app.ActionsandStatusTab = uitab(app.TabGroup);
            app.ActionsandStatusTab.Title = 'Actions and Status';
            app.connectButton = uibutton(app.ActionsandStatusTab, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connect, true);
            app.connectButton.Text = 'Connect';
            app.startRecButton = uibutton(app.ActionsandStatusTab, 'push');
            app.startRecButton.ButtonPushedFcn = createCallbackFcn(app, @start_recording, true);
            app.startRecButton.Enable = 'off';
            app.startRecButton.Text = 'Start Recording';
            app.stopRecButton = uibutton(app.ActionsandStatusTab, 'push');
            app.stopRecButton.ButtonPushedFcn = createCallbackFcn(app, @stop_recording, true);
            app.stopRecButton.Enable = 'off';
            app.stopRecButton.Text = 'Stop Recording';
            app.StatusTextAreaLabel = uilabel(app.ActionsandStatusTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.statusField = uitextarea(app.ActionsandStatusTab);
            app.disconnectButton = uibutton(app.ActionsandStatusTab, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnect, true);
            app.disconnectButton.Enable = 'off';
            app.disconnectButton.Text = 'Disconnect';
            app.startStimButton = uibutton(app.ActionsandStatusTab, 'push');
            app.startStimButton.ButtonPushedFcn = createCallbackFcn(app, @start_stim, true);
            app.startStimButton.Enable = 'off';
            app.startStimButton.Text = 'Start Stimulation';
            app.stopStimButton = uibutton(app.ActionsandStatusTab, 'state');
            app.stopStimButton.ValueChangedFcn = createCallbackFcn(app, @stop_stim, true);
            app.stopStimButton.Enable = 'off';
            app.stopStimButton.Text = 'Stop Stimulation';
            % Show the figure after all components are created
            app.AcqStimControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sleep_stimulation_protocol_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcqStimControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcqStimControllerUIFigure)
        end
    end
end