classdef test_connect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        testConnectFigure        matlab.ui.Figure
        SystemMACEditFieldLabel  matlab.ui.control.Label
        macField                 matlab.ui.control.EditField
        connectButton            matlab.ui.control.Button
        disconnectButton         matlab.ui.control.Button
        StatusTextAreaLabel      matlab.ui.control.Label
        statusField              matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            addpath('c:\Program Files (x86)\AlphaOmega\Neuro Omega System SDK\MATLAB_SDK\');
        end

        % Button pushed function: connectButton
        function attempt_connect(app, event)
            %% initiate connection
            app.statusField.Value = 'Attempting to connect';
            try
                ret = AO_DefaultStartConnection(app.macField.Value);
            catch e
                app.statusField.Value = ['Connection failed with error: ' e.message ];
                return
            end
            if( ret )
                app.statusField.Value = ['Connection failed with return value ' num2str(ret)];
                return
            else
                app.statusField.Value = 'Connection initiated.';
            end
            
            %% ensure connection complete
            for j=1:2
              pause(2);
              ret = AO_IsConnected();
              if( ret == 1 )
                app.statusField.Value = 'Connection completed.';
                break;
              end
            end
        end

        % Button pushed function: disconnectButton
        function attempt_disconnect(app, event)
            %% disconnect
            app.statusField.Value = 'Attempting to disconnect';
            try
                ret = AO_CloseConnection();
            catch e
                app.statusField.Value = ['Disconnect failed with error: ' e.message ];
            end
            if( ret )
                app.statusField.Value = ['Disconnect failed with return value ' num2str(ret)];
            else
                app.statusField.Value = 'Disconnect complete.';
            end

        end

        % Close request function: testConnectFigure
        function finalize(app, event)
            delete(app)
            AO_CloseConnection();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.testConnectFigure = uifigure;
            app.testConnectFigure.Name = 'UI Figure';
            app.testConnectFigure.CloseRequestFcn = createCallbackFcn(app, @finalize, true);
            app.SystemMACEditFieldLabel = uilabel(app.testConnectFigure);
            app.SystemMACEditFieldLabel.Text = 'System MAC:';
            app.macField = uieditfield(app.testConnectFigure, 'text');
            app.macField.Value = 'a8:1b:6a:27:dc:e9';
            app.connectButton = uibutton(app.testConnectFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @attempt_connect, true);
            app.connectButton.Text = 'Connect';
            app.disconnectButton = uibutton(app.testConnectFigure, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @attempt_disconnect, true);
            app.disconnectButton.Text = 'Disconnect';
            app.StatusTextAreaLabel = uilabel(app.testConnectFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.statusField = uitextarea(app.testConnectFigure);
            app.statusField.Editable = 'off';
            app.statusField.Value = {'Not connected.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = test_connect

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.testConnectFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.testConnectFigure)
        end
    end
end